classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ChaseTheFireUIFigure           matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        NewMenu                        matlab.ui.container.Menu
        MapMenu                        matlab.ui.container.Menu
        ValuesMenu                     matlab.ui.container.Menu
        SheetMenu                      matlab.ui.container.Menu
        OpenMenu                       matlab.ui.container.Menu
        sheetMenu                      matlab.ui.container.Menu
        projectMenu                    matlab.ui.container.Menu
        SaveMenu                       matlab.ui.container.Menu
        SaveAsMenu                     matlab.ui.container.Menu
        PrintMenu                      matlab.ui.container.Menu
        ExitMenu                       matlab.ui.container.Menu
        EditMenu                       matlab.ui.container.Menu
        UndoMenu                       matlab.ui.container.Menu
        RedoMenu                       matlab.ui.container.Menu
        ClearAllMenu                   matlab.ui.container.Menu
        CopyMenu                       matlab.ui.container.Menu
        CutMenu                        matlab.ui.container.Menu
        PasteMenu                      matlab.ui.container.Menu
        RandomeDataMenu                matlab.ui.container.Menu
        Sample1Menu                    matlab.ui.container.Menu
        Sample2Menu                    matlab.ui.container.Menu
        Sample3Menu                    matlab.ui.container.Menu
        ViewMenu                       matlab.ui.container.Menu
        ShowStatusBarMenu              matlab.ui.container.Menu
        ShowMapMenu                    matlab.ui.container.Menu
        ShowtoolStripsMenu             matlab.ui.container.Menu
        ShowCFMenu                     matlab.ui.container.Menu
        ShowROCMenu                    matlab.ui.container.Menu
        ShowDataRegMenu                matlab.ui.container.Menu
        FullScreenMenu                 matlab.ui.container.Menu
        HideStatusBarMenu              matlab.ui.container.Menu
        HideMapMenu                    matlab.ui.container.Menu
        HideToolstripMenu              matlab.ui.container.Menu
        ControlsMenu                   matlab.ui.container.Menu
        SetMonthMenu                   matlab.ui.container.Menu
        janMenu                        matlab.ui.container.Menu
        febMenu                        matlab.ui.container.Menu
        marMenu                        matlab.ui.container.Menu
        aprMenu                        matlab.ui.container.Menu
        mayMenu                        matlab.ui.container.Menu
        junMenu                        matlab.ui.container.Menu
        julMenu                        matlab.ui.container.Menu
        augMenu                        matlab.ui.container.Menu
        sapMenu                        matlab.ui.container.Menu
        octMenu                        matlab.ui.container.Menu
        novMenu                        matlab.ui.container.Menu
        decMenu                        matlab.ui.container.Menu
        SetDayMenu                     matlab.ui.container.Menu
        sunMenu                        matlab.ui.container.Menu
        monMenu                        matlab.ui.container.Menu
        tueMenu                        matlab.ui.container.Menu
        wedMenu                        matlab.ui.container.Menu
        thuMenu                        matlab.ui.container.Menu
        friMenu                        matlab.ui.container.Menu
        satMenu                        matlab.ui.container.Menu
        ShowMenu                       matlab.ui.container.Menu
        CFMenu                         matlab.ui.container.Menu
        ROCMenu                        matlab.ui.container.Menu
        DataRegMenu                    matlab.ui.container.Menu
        HelpMenu                       matlab.ui.container.Menu
        ChassersHelpMenu               matlab.ui.container.Menu
        KeyboardShortcutsMenu          matlab.ui.container.Menu
        ApplicationUserGideMenu        matlab.ui.container.Menu
        NextUpdateMenu                 matlab.ui.container.Menu
        AboutUsMenu                    matlab.ui.container.Menu
        chasethefire_1                 matlab.ui.container.Panel
        Panel_6                        matlab.ui.container.Panel
        GridLayout                     matlab.ui.container.GridLayout
        CoordinatesLabel               matlab.ui.control.Label
        EnvironmentLabel               matlab.ui.control.Label
        PannelFunctionLabel            matlab.ui.control.Label
        FireAreaPredictionLabel        matlab.ui.control.Label
        SomeFactorsLabel               matlab.ui.control.Label
        periodDateLabel                matlab.ui.control.Label
        Panel_2                        matlab.ui.container.Panel
        xedit                          matlab.ui.control.EditField
        XLabel                         matlab.ui.control.Label
        xl                             matlab.ui.control.Label
        YLabel                         matlab.ui.control.Label
        yedit                          matlab.ui.control.EditField
        yl                             matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        Panel_5                        matlab.ui.container.Panel
        Label_7                        matlab.ui.control.Label
        Label_8                        matlab.ui.control.Label
        Label_9                        matlab.ui.control.Label
        TempEditField_2Label           matlab.ui.control.Label
        tempedit                       matlab.ui.control.EditField
        tl                             matlab.ui.control.Label
        rhl                            matlab.ui.control.Label
        RHEditField_2Label             matlab.ui.control.Label
        rhedit                         matlab.ui.control.EditField
        wl                             matlab.ui.control.Label
        WindEditField_3Label           matlab.ui.control.Label
        windedit                       matlab.ui.control.EditField
        rl                             matlab.ui.control.Label
        RainEditField_2Label           matlab.ui.control.Label
        rainedit                       matlab.ui.control.EditField
        ShowFigureLabel                matlab.ui.control.Label
        ButtonGroup                    matlab.ui.container.ButtonGroup
        sl                             matlab.ui.control.Label
        dddp                           matlab.ui.control.DropDown
        Button                         matlab.ui.control.Button
        Panel_4                        matlab.ui.container.Panel
        ml                             matlab.ui.control.Label
        dl                             matlab.ui.control.Label
        Label_4                        matlab.ui.control.Label
        mdd                            matlab.ui.control.DropDown
        ddd                            matlab.ui.control.DropDown
        MonthLabel                     matlab.ui.control.Label
        DayLabel                       matlab.ui.control.Label
        Panel_3                        matlab.ui.container.Panel
        Label_3                        matlab.ui.control.Label
        Label_5                        matlab.ui.control.Label
        Label_6                        matlab.ui.control.Label
        FFMCEditFieldLabel             matlab.ui.control.Label
        ffmcedit                       matlab.ui.control.EditField
        fl                             matlab.ui.control.Label
        dml                            matlab.ui.control.Label
        DMCEditField_2Label            matlab.ui.control.Label
        dmcedit                        matlab.ui.control.EditField
        dcl                            matlab.ui.control.Label
        DCLabel                        matlab.ui.control.Label
        dcedit                         matlab.ui.control.EditField
        il                             matlab.ui.control.Label
        ISILabel                       matlab.ui.control.Label
        isiedit                        matlab.ui.control.EditField
        PredictButton                  matlab.ui.control.StateButton
        arealabel                      matlab.ui.control.Label
        areafinal                      matlab.ui.control.EditField
        areadet                        matlab.ui.control.Label
        mapl                           matlab.ui.control.Label
        MapsButton                     matlab.ui.control.StateButton
        UIAxes                         matlab.ui.control.UIAxes
        TypeFirePerdictionButton       matlab.ui.control.StateButton
        chasethefire_2                 matlab.ui.container.Panel
        PredictButton_2                matlab.ui.control.StateButton
        arealabel_2                    matlab.ui.control.Label
        Image2                         matlab.ui.control.Image
        areafinal_2                    matlab.ui.control.EditField
        FlipFireAreaPredictionButton   matlab.ui.control.StateButton
        Panel_7                        matlab.ui.container.Panel
        GridLayout_2                   matlab.ui.container.GridLayout
        SomeFactorsLabel_3             matlab.ui.control.Label
        PannelFunctionLabel_2          matlab.ui.control.Label
        TypeFirePerdictionLabel        matlab.ui.control.Label
        TemperatureLabel               matlab.ui.control.Label
        Panel_8                        matlab.ui.container.Panel
        Label_13                       matlab.ui.control.Label
        BrightnessLabel                matlab.ui.control.Label
        briedit2                       matlab.ui.control.EditField
        fl_2                           matlab.ui.control.Label
        dml_2                          matlab.ui.control.Label
        Bright_T31Label                matlab.ui.control.Label
        briedit3                       matlab.ui.control.EditField
        briedit2_2                     matlab.ui.control.EditField
        fl_3                           matlab.ui.control.Label
        bright_t31Label                matlab.ui.control.Label
        Panel_10                       matlab.ui.container.Panel
        Label_16                       matlab.ui.control.Label
        Label_17                       matlab.ui.control.Label
        Label_18                       matlab.ui.control.Label
        ScanLabel                      matlab.ui.control.Label
        scanedit                       matlab.ui.control.EditField
        tl_2                           matlab.ui.control.Label
        rhl_2                          matlab.ui.control.Label
        TrackLabel                     matlab.ui.control.Label
        trackedit                      matlab.ui.control.EditField
        wl_2                           matlab.ui.control.Label
        ConfidenceLabel                matlab.ui.control.Label
        conedit                        matlab.ui.control.EditField
        rl_2                           matlab.ui.control.Label
        FRPLabel                       matlab.ui.control.Label
        frpedit                        matlab.ui.control.EditField
        ShowFigureLabel_2              matlab.ui.control.Label
        ButtonGroup_2                  matlab.ui.container.ButtonGroup
        xl_4                           matlab.ui.control.Label
        ddd_4                          matlab.ui.control.DropDown
        Button_2                       matlab.ui.control.Button
        DimensionsLabel                matlab.ui.control.Label
        Panel_12                       matlab.ui.container.Panel
        latedit                        matlab.ui.control.EditField
        LatitudeLabel                  matlab.ui.control.Label
        xl_3                           matlab.ui.control.Label
        LongitudeLabel                 matlab.ui.control.Label
        laonedit                       matlab.ui.control.EditField
        Label_20                       matlab.ui.control.Label
        MoreDetailsLabel               matlab.ui.control.Label
        Panel_9                        matlab.ui.container.Panel
        SatelliteLabel                 matlab.ui.control.Label
        xl_2                           matlab.ui.control.Label
        Label_15                       matlab.ui.control.Label
        sdd                            matlab.ui.control.DropDown
        timedit                        matlab.ui.control.EditField
        AcqTimeLabel_2                 matlab.ui.control.Label
        DayNightLabel                  matlab.ui.control.Label
        tdd                            matlab.ui.control.DropDown
        StatusBarLabel                 matlab.ui.control.Label
        ChasersAllRightsReservedLabel  matlab.ui.control.Label
        DateLabel                      matlab.ui.control.Label
        Label_10                       matlab.ui.control.Label
        Image                          matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            IM = imread('site.png')
            imshow(IM,'Parent',app.UIAxes);
        end

        % Value changed function: MapsButton
        function MapsButtonValueChanged(app, event)
            value = app.MapsButton.Value;
            %map_buttun
            global image_src;
            switch value
                case 1
                    IM = imread('maps.png')
                    imshow(IM,'Parent',app.UIAxes);
                    app.MapsButton.Icon = 'site.png';
                    app.MapsButton.Text = 'Satellite'
                case 0
                IM = imread('site.png')
                imshow(IM,'Parent',app.UIAxes);
                app.MapsButton.Icon = 'maps.png';
                app.MapsButton.Text = 'Maps'
            end
            
        end

        % Value changed function: PredictButton
        function PredictButtonValueChanged(app, event)
            value = app.PredictButton.Value;
            switch value
                case 1
                    %ALL CODE
                    app.areafinal.Visible = true;
                    app.areadet.Visible = true;
                    app.xedit.Enable = false;
                    app.yedit.Enable = false;
                    app.mdd.Enable = false;
                    app.ddd.Enable = false;
                    app.ffmcedit.Enable = false;
                    app.dmcedit.Enable = false;
                    app.dcedit.Enable = false;
                    app.isiedit.Enable = false;
                    app.tempedit.Enable = false;
                    app.rhedit.Enable = false;
                    app.windedit.Enable = false;
                    app.rainedit.Enable = false;
                    app.PredictButton.Text = "New Sheet"
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    %COORDINATES
                        X = str2double(app.xedit.Value);
                        Y = str2double(app.yedit.Value);
                    %PERIOD DATE
                        month = "jan" ;
                        day = "sun" ;
                        switch(app.mdd.Value)
                            case 'jan'
                                month = "jan";
                            case 'feb'
                                month = "feb";
                            case 'mar'
                                month = "mar";
                            case 'apr'
                                month = "apr";
                            case 'may'
                                month = "may";
                            case 'jun'
                                month = "jun";
                            case 'jul'
                                month = "jul";
                            case 'aug'
                                month = "aug";
                            case 'sep'
                                month = "sep";
                            case 'oct'
                                month = "oct";
                            case 'nov'
                                month = "nov";
                            case 'dec'
                                month = "dec";
                            otherwise
                                month = "jan";
                        end
                        
                        switch(app.ddd.Value)
                            case 'sun'
                                day = "sun";
                            case 'mon'
                                day = "mon";
                            case 'tue'
                                day = "tue";
                            case 'wed'
                                day = "wed";
                            case 'thu'
                                day = "thu";
                            case 'fri'
                                day = "fri";
                            case 'sat'
                                day = "sat";
                            otherwise
                                day = "sun";
                        end
                       
                    %SOME FACTORS
                        FFMC = str2double(app.ffmcedit.Value);
                        DMC = str2double(app.dmcedit.Value);
                        DC = str2double(app.dcedit.Value);
                        ISI = str2double(app.isiedit.Value);
                    %ENVIRONMENT
                        temp = str2double(app.tempedit.Value);
                        RH = str2double(app.rhedit.Value);
                        wind = str2double(app.windedit.Value);
                        rain = str2double(app.rainedit.Value);
                    %RESULTS
                    area = 0;
                    Fire = 0;
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                  
                    
                    load 'matlab2.mat';
                    T2 = table(X, Y, month, day, FFMC, DMC, DC, ISI, temp, RH, wind, rain, Fire);
                    yfit2 = RFClass.predictFcn(T2) 
                    
                    
                    load 'matlab.mat';
                    T = table(X, Y, month, day, FFMC, DMC, DC, ISI, temp, RH, wind, rain, area);
                    yfit = RF.predictFcn(T)


                    if ~yfit2
                        app.areafinal.Value = '0'
                    else
                       yy = sprintf('%.4f',yfit)
                       app.areafinal.Value = yy;
                       X =int16(X)
                       Y =int16(Y) 
                       yfit5 = int16(yfit);
                       plot(app.UIAxes,X, Y, '.r', 'MarkerSize',yfit5);
            
                       
                    end
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                case 0
                    %
                    app.areafinal.Value = '?'
                    app.xedit.Enable = true;
                    app.yedit.Enable = true;
                    app.mdd.Enable = true;
                    app.ddd.Enable = true;
                    app.ffmcedit.Enable = true;
                    app.dmcedit.Enable = true;
                    app.dcedit.Enable = true;
                    app.isiedit.Enable = true;
                    app.tempedit.Enable = true;
                    app.rhedit.Enable = true;
                    app.windedit.Enable = true;
                    app.rainedit.Enable = true;
                    app.PredictButton.Text = "Predict"
                case 0
            end
        end

        % Menu selected function: Sample1Menu
        function Sample1MenuSelected(app, event)
             app.xedit.Value = '80';
             app.yedit.Value = '100';
             app.mdd.Value = 'sep';
             app.ddd.Value = 'sun';
             app.ffmcedit.Value = '93.5';
             app.dmcedit.Value = '149.3';
             app.dcedit.Value = '728.6';
             app.isiedit.Value = '8.1';
             app.tempedit.Value = '22.9';
             app.rhedit.Value = '39';
             app.windedit.Value = '4.9';
             app.rainedit.Value = '0';
        end

        % Menu selected function: Sample2Menu
        function Sample2MenuSelected(app, event)
             app.xedit.Value = '100';
             app.yedit.Value = '60';
             app.mdd.Value = 'aug';
             app.ddd.Value = 'sun';
             app.ffmcedit.Value = '91.4';
             app.dmcedit.Value = '142.4';
             app.dcedit.Value = '601.4';
             app.isiedit.Value = '10.6';
             app.tempedit.Value = '19.6';
             app.rhedit.Value = '41';
             app.windedit.Value = '5.8';
             app.rainedit.Value = '0';
        end

        % Menu selected function: Sample3Menu
        function Sample3MenuSelected(app, event)
            app.xedit.Value = '300';
             app.yedit.Value = '200';
             app.mdd.Value = 'sep';
             app.ddd.Value = 'sat';
             app.ffmcedit.Value = '92.5';
             app.dmcedit.Value = '121.1';
             app.dcedit.Value = '674.4';
             app.isiedit.Value = '8.6';
             app.tempedit.Value = '25.1';
             app.rhedit.Value = '27';
             app.windedit.Value = '4';
             app.rainedit.Value = '0';
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            switch(app.dddp.Value)
                case 'CF'
                    cf;
                case 'ROC'
                    roc;
                case 'DataReg'
                    DataReg;
            end
        end

        % Value changed function: PredictButton_2
        function PredictButton_2ValueChanged(app, event)
            value = app.PredictButton_2.Value;
            
            latitude = str2double(app.latedit.Value)
            longitude = str2double(app.laonedit.Value)
            brightness = str2double(app.briedit2.Value)
            scan = str2double(app.scanedit.Value)
            track = str2double(app.trackedit.Value)
            acq_time = str2double(app.timedit.Value)
            
            switch(app.sdd.Value)
                case 'Terra'
                    satellite= "Terra"
                case 'Aqua'
                    satellite = "Aqua"
            end
            instrument = "DOMIS"
            confidence = str2double(app.conedit.Value)
            version = "6.03"
            bright_t31 = str2double(app.briedit3.Value)
            frp = str2double(app.frpedit.Value)
            switch(app.tdd.Value)
                case 'Day'
                    daynight = "D"
                case 'Night'
                    daynight = "N"
            end
            type = 10;
            
            
             load 'matlab3.mat';
             %'latitude', 'longitude', 'brightness', 'scan', 'track', 'acq_time', 'satellite', 'instrument', 'confidence', 'version', 'bright_t31', 'frp', 'daynight'
                    T3 = table(latitude, longitude, brightness, scan,track, acq_time, satellite, instrument, confidence, version, bright_t31, frp, daynight, type);
                    yyfit = trainedModel.predictFcn(T3);
                    
                    app.areafinal_2.Value = type;
                    
            
        end

        % Callback function
        function sp1ValueChanged(app, event)
            value = app.sp1.Value
           
           
        end

        % Value changed function: TypeFirePerdictionButton
        function TypeFirePerdictionButtonValueChanged(app, event)
        app.TypeFirePerdictionButton.Text = 'Please Wait ...'
            app.FlipFireAreaPredictionButton.Text = 'FireAreaPredictionButton'
            value = app.TypeFirePerdictionButton.Value;
            app.chasethefire_2.Visible = true;
            app.chasethefire_1.Visible = false;
            
        end

        % Value changed function: FlipFireAreaPredictionButton
        function FlipFireAreaPredictionButtonValueChanged(app, event)
        app.FlipFireAreaPredictionButton.Text = 'Please Wait ...'
            app.TypeFirePerdictionButton.Text = 'TypeFirePerdictionButton'
            value = app.FlipFireAreaPredictionButton.Value;
            app.chasethefire_1.Visible = true;
            app.chasethefire_2.Visible = false;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ChaseTheFireUIFigure = uifigure('Visible', 'off');
            app.ChaseTheFireUIFigure.Name = 'Chase The Fire';
            app.FileMenu = uimenu(app.ChaseTheFireUIFigure);
            app.FileMenu.Tooltip = {'File'};
            app.FileMenu.Text = 'File';
            app.NewMenu = uimenu(app.FileMenu);
            app.NewMenu.Text = 'New';
            app.MapMenu = uimenu(app.NewMenu);
            app.MapMenu.Accelerator = '1';
            app.MapMenu.Text = 'Map';
            app.ValuesMenu = uimenu(app.NewMenu);
            app.ValuesMenu.Accelerator = '2';
            app.ValuesMenu.Text = 'Values';
            app.SheetMenu = uimenu(app.NewMenu);
            app.SheetMenu.Accelerator = '3';
            app.SheetMenu.Text = 'Sheet';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.Separator = 'on';
            app.OpenMenu.Text = 'Open';
            app.sheetMenu = uimenu(app.OpenMenu);
            app.sheetMenu.Accelerator = 'o';
            app.sheetMenu.Text = 'sheet';
            app.projectMenu = uimenu(app.OpenMenu);
            app.projectMenu.Accelerator = 'k';
            app.projectMenu.Text = 'project';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.Separator = 'on';
            app.SaveMenu.Accelerator = 's';
            app.SaveMenu.Text = 'Save';
            app.SaveAsMenu = uimenu(app.FileMenu);
            app.SaveAsMenu.Accelerator = 'a';
            app.SaveAsMenu.Text = 'Save As';
            app.PrintMenu = uimenu(app.FileMenu);
            app.PrintMenu.Separator = 'on';
            app.PrintMenu.Accelerator = 'p';
            app.PrintMenu.Text = 'Print';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.Separator = 'on';
            app.ExitMenu.Accelerator = 'e';
            app.ExitMenu.Text = 'Exit';
            app.EditMenu = uimenu(app.ChaseTheFireUIFigure);
            app.EditMenu.Tooltip = {'Edit'};
            app.EditMenu.Text = 'Edit';
            app.UndoMenu = uimenu(app.EditMenu);
            app.UndoMenu.Accelerator = 'z';
            app.UndoMenu.Text = 'Undo';
            app.RedoMenu = uimenu(app.EditMenu);
            app.RedoMenu.Accelerator = 'y';
            app.RedoMenu.Text = 'Redo';
            app.ClearAllMenu = uimenu(app.EditMenu);
            app.ClearAllMenu.Separator = 'on';
            app.ClearAllMenu.Accelerator = 'd';
            app.ClearAllMenu.Text = 'Clear All';
            app.CopyMenu = uimenu(app.EditMenu);
            app.CopyMenu.Separator = 'on';
            app.CopyMenu.Accelerator = 'c';
            app.CopyMenu.Text = 'Copy';
            app.CutMenu = uimenu(app.EditMenu);
            app.CutMenu.Accelerator = 'x';
            app.CutMenu.Text = 'Cut';
            app.PasteMenu = uimenu(app.EditMenu);
            app.PasteMenu.Accelerator = 'v';
            app.PasteMenu.Text = 'Paste';
            app.RandomeDataMenu = uimenu(app.EditMenu);
            app.RandomeDataMenu.Separator = 'on';
            app.RandomeDataMenu.Accelerator = 'r';
            app.RandomeDataMenu.Text = 'Randome Data';
            app.Sample1Menu = uimenu(app.RandomeDataMenu);
            app.Sample1Menu.MenuSelectedFcn = createCallbackFcn(app, @Sample1MenuSelected, true);
            app.Sample1Menu.Text = 'Sample1';
            app.Sample2Menu = uimenu(app.RandomeDataMenu);
            app.Sample2Menu.MenuSelectedFcn = createCallbackFcn(app, @Sample2MenuSelected, true);
            app.Sample2Menu.Text = 'Sample2';
            app.Sample3Menu = uimenu(app.RandomeDataMenu);
            app.Sample3Menu.MenuSelectedFcn = createCallbackFcn(app, @Sample3MenuSelected, true);
            app.Sample3Menu.Text = 'Sample3';
            app.ViewMenu = uimenu(app.ChaseTheFireUIFigure);
            app.ViewMenu.Tooltip = {'View'};
            app.ViewMenu.Text = 'View';
            app.ShowStatusBarMenu = uimenu(app.ViewMenu);
            app.ShowStatusBarMenu.Tooltip = {'Show Status Bar'};
            app.ShowStatusBarMenu.Text = 'Show Status Bar';
            app.ShowMapMenu = uimenu(app.ViewMenu);
            app.ShowMapMenu.Tooltip = {'Show Status Bar'};
            app.ShowMapMenu.Text = 'Show Map';
            app.ShowtoolStripsMenu = uimenu(app.ViewMenu);
            app.ShowtoolStripsMenu.Tooltip = {'Show toolStrips'};
            app.ShowtoolStripsMenu.Text = 'Show toolStrips';
            app.ShowCFMenu = uimenu(app.ViewMenu);
            app.ShowCFMenu.Tooltip = {'Show CF'};
            app.ShowCFMenu.Separator = 'on';
            app.ShowCFMenu.Text = 'Show CF';
            app.ShowROCMenu = uimenu(app.ViewMenu);
            app.ShowROCMenu.Tooltip = {'Show ROC'};
            app.ShowROCMenu.Text = 'Show ROC';
            app.ShowDataRegMenu = uimenu(app.ViewMenu);
            app.ShowDataRegMenu.Tooltip = {'Show DataReg'};
            app.ShowDataRegMenu.Text = 'Show DataReg';
            app.FullScreenMenu = uimenu(app.ViewMenu);
            app.FullScreenMenu.Tooltip = {'Full Screen'};
            app.FullScreenMenu.Separator = 'on';
            app.FullScreenMenu.Accelerator = 'f';
            app.FullScreenMenu.Text = 'Full Screen';
            app.HideStatusBarMenu = uimenu(app.ViewMenu);
            app.HideStatusBarMenu.Tooltip = {'Hide Status Bar'};
            app.HideStatusBarMenu.Separator = 'on';
            app.HideStatusBarMenu.Text = 'Hide Status Bar';
            app.HideMapMenu = uimenu(app.ViewMenu);
            app.HideMapMenu.Tooltip = {'Hide Map'};
            app.HideMapMenu.Text = 'Hide Map';
            app.HideToolstripMenu = uimenu(app.ViewMenu);
            app.HideToolstripMenu.Tooltip = {'Hide Toolstrip'};
            app.HideToolstripMenu.Text = 'Hide Toolstrip';
            app.ControlsMenu = uimenu(app.ChaseTheFireUIFigure);
            app.ControlsMenu.Tooltip = {'Controls'};
            app.ControlsMenu.Text = 'Controls';
            app.SetMonthMenu = uimenu(app.ControlsMenu);
            app.SetMonthMenu.Text = 'Set Month';
            app.janMenu = uimenu(app.SetMonthMenu);
            app.janMenu.Tooltip = {'1st Month'};
            app.janMenu.Text = 'jan';
            app.febMenu = uimenu(app.SetMonthMenu);
            app.febMenu.Tooltip = {'2nd Month'};
            app.febMenu.Text = 'feb';
            app.marMenu = uimenu(app.SetMonthMenu);
            app.marMenu.Tooltip = {'3rd Month'};
            app.marMenu.Text = 'mar';
            app.aprMenu = uimenu(app.SetMonthMenu);
            app.aprMenu.Tooltip = {'4th Month'};
            app.aprMenu.Text = 'apr';
            app.mayMenu = uimenu(app.SetMonthMenu);
            app.mayMenu.Tooltip = {'5th Month'};
            app.mayMenu.Text = 'may';
            app.junMenu = uimenu(app.SetMonthMenu);
            app.junMenu.Tooltip = {'6th Month'};
            app.junMenu.Text = 'jun';
            app.julMenu = uimenu(app.SetMonthMenu);
            app.julMenu.Tooltip = {'7th Month'};
            app.julMenu.Text = 'jul';
            app.augMenu = uimenu(app.SetMonthMenu);
            app.augMenu.Tooltip = {'8th Month'};
            app.augMenu.Text = 'aug';
            app.sapMenu = uimenu(app.SetMonthMenu);
            app.sapMenu.Tooltip = {'9th Month'};
            app.sapMenu.Text = 'sap';
            app.octMenu = uimenu(app.SetMonthMenu);
            app.octMenu.Tooltip = {'10th Month'};
            app.octMenu.Text = 'oct';
            app.novMenu = uimenu(app.SetMonthMenu);
            app.novMenu.Tooltip = {'11th Month'};
            app.novMenu.Text = 'nov';
            app.decMenu = uimenu(app.SetMonthMenu);
            app.decMenu.Tooltip = {'12th Month'};
            app.decMenu.Text = 'dec';
            app.SetDayMenu = uimenu(app.ControlsMenu);
            app.SetDayMenu.Text = 'Set Day';
            app.sunMenu = uimenu(app.SetDayMenu);
            app.sunMenu.Tooltip = {'1st Day'};
            app.sunMenu.Text = 'sun';
            app.monMenu = uimenu(app.SetDayMenu);
            app.monMenu.Tooltip = {'2nd  Day'};
            app.monMenu.Text = 'mon';
            app.tueMenu = uimenu(app.SetDayMenu);
            app.tueMenu.Tooltip = {'3rd Day'};
            app.tueMenu.Text = 'tue';
            app.wedMenu = uimenu(app.SetDayMenu);
            app.wedMenu.Tooltip = {'4th Day'};
            app.wedMenu.Text = 'wed';
            app.thuMenu = uimenu(app.SetDayMenu);
            app.thuMenu.Tooltip = {'5th Day'};
            app.thuMenu.Text = 'thu';
            app.friMenu = uimenu(app.SetDayMenu);
            app.friMenu.Tooltip = {'6th Day'};
            app.friMenu.Text = 'fri';
            app.satMenu = uimenu(app.SetDayMenu);
            app.satMenu.Tooltip = {'7th Day'};
            app.satMenu.Text = 'sat';
            app.ShowMenu = uimenu(app.ControlsMenu);
            app.ShowMenu.Separator = 'on';
            app.ShowMenu.Text = 'Show';
            app.CFMenu = uimenu(app.ShowMenu);
            app.CFMenu.Text = 'CF';
            app.ROCMenu = uimenu(app.ShowMenu);
            app.ROCMenu.Text = 'ROC';
            app.DataRegMenu = uimenu(app.ShowMenu);
            app.DataRegMenu.Text = 'DataReg';
            app.HelpMenu = uimenu(app.ChaseTheFireUIFigure);
            app.HelpMenu.Tooltip = {'Help'};
            app.HelpMenu.Text = 'Help';
            app.ChassersHelpMenu = uimenu(app.HelpMenu);
            app.ChassersHelpMenu.Tooltip = {'Chassers Help'};
            app.ChassersHelpMenu.Accelerator = 'r';
            app.ChassersHelpMenu.Text = 'Chassers Help';
            app.KeyboardShortcutsMenu = uimenu(app.HelpMenu);
            app.KeyboardShortcutsMenu.Tooltip = {'Keyboard Shortcuts'};
            app.KeyboardShortcutsMenu.Accelerator = 'q';
            app.KeyboardShortcutsMenu.Text = 'Keyboard Shortcuts';
            app.ApplicationUserGideMenu = uimenu(app.HelpMenu);
            app.ApplicationUserGideMenu.Tooltip = {'Application User Gide'};
            app.ApplicationUserGideMenu.Separator = 'on';
            app.ApplicationUserGideMenu.Accelerator = 'g';
            app.ApplicationUserGideMenu.Text = 'Application User Gide';
            app.NextUpdateMenu = uimenu(app.HelpMenu);
            app.NextUpdateMenu.Tooltip = {'Check for updayes'};
            app.NextUpdateMenu.Separator = 'on';
            app.NextUpdateMenu.Accelerator = 'u';
            app.NextUpdateMenu.Text = 'Next Update';
            app.AboutUsMenu = uimenu(app.HelpMenu);
            app.AboutUsMenu.Tooltip = {'Chasers'};
            app.AboutUsMenu.Separator = 'on';
            app.AboutUsMenu.Accelerator = 'w';
            app.AboutUsMenu.Text = 'About Us';
            app.chasethefire_1 = uipanel(app.ChaseTheFireUIFigure);
            app.chasethefire_1.Title = 'Chase The Fire';
            app.Panel_6 = uipanel(app.chasethefire_1);
            app.GridLayout = uigridlayout(app.Panel_6);
            app.CoordinatesLabel = uilabel(app.GridLayout);
            app.CoordinatesLabel.Text = '   Coordinates';
            app.EnvironmentLabel = uilabel(app.GridLayout);
            app.EnvironmentLabel.Text = '   Environment';
            app.PannelFunctionLabel = uilabel(app.GridLayout);
            app.PannelFunctionLabel.Text = '  Pannel Function';
            app.FireAreaPredictionLabel = uilabel(app.GridLayout);
            app.FireAreaPredictionLabel.Text = '🔥 Fire Area Prediction  🔥';
            app.SomeFactorsLabel = uilabel(app.GridLayout);
            app.SomeFactorsLabel.Text = '  Some Factors';
            app.periodDateLabel = uilabel(app.GridLayout);
            app.periodDateLabel.Text = '   period Date';
            app.Panel_2 = uipanel(app.GridLayout);
            app.xedit = uieditfield(app.Panel_2, 'text');
            app.xedit.Tooltip = {'Enter X-Axis Here'};
            app.xedit.Value = '10';
            app.XLabel = uilabel(app.Panel_2);
            app.XLabel.Text = 'X';
            app.xl = uilabel(app.Panel_2);
            app.xl.Text = {'X-axis coordinate within The Montesinho '; 'park map From 10 to 500'};
            app.YLabel = uilabel(app.Panel_2);
            app.YLabel.Text = 'Y';
            app.yedit = uieditfield(app.Panel_2, 'text');
            app.yedit.Tooltip = {'Enter Y-Axis Here'};
            app.yedit.Value = '10';
            app.yl = uilabel(app.Panel_2);
            app.yl.Text = {'Y-axis coordinate within The Montesinho'; ' park map From 10 to 400'};
            app.Label_2 = uilabel(app.Panel_2);
            app.Label_2.Text = '';
            app.Panel_5 = uipanel(app.GridLayout);
            app.Label_7 = uilabel(app.Panel_5);
            app.Label_7.Text = '';
            app.Label_8 = uilabel(app.Panel_5);
            app.Label_8.Text = '';
            app.Label_9 = uilabel(app.Panel_5);
            app.Label_9.Text = '';
            app.TempEditField_2Label = uilabel(app.Panel_5);
            app.TempEditField_2Label.Text = 'Temp';
            app.tempedit = uieditfield(app.Panel_5, 'text');
            app.tempedit.Tooltip = {'Enter Temperature Here'};
            app.tempedit.Value = '22.9';
            app.tl = uilabel(app.Panel_5);
            app.tl.Text = {'temperature in Celsius '; 'degrees: 2.2 to 33.30'};
            app.rhl = uilabel(app.Panel_5);
            app.rhl.Text = 'relative humidity in %: 15.0 to 100';
            app.RHEditField_2Label = uilabel(app.Panel_5);
            app.RHEditField_2Label.Text = 'RH';
            app.rhedit = uieditfield(app.Panel_5, 'text');
            app.rhedit.Tooltip = {'Enter Relative Humidity Here'};
            app.rhedit.Value = '39';
            app.wl = uilabel(app.Panel_5);
            app.wl.Text = 'wind speed in km/h: 0.40 to 9.40';
            app.WindEditField_3Label = uilabel(app.Panel_5);
            app.WindEditField_3Label.Text = 'Wind';
            app.windedit = uieditfield(app.Panel_5, 'text');
            app.windedit.Tooltip = {'Enter Wind Speed Here'};
            app.windedit.Value = '4.9';
            app.rl = uilabel(app.Panel_5);
            app.rl.Text = 'outside rain in mm/m2 : 0.0 to 6.4';
            app.RainEditField_2Label = uilabel(app.Panel_5);
            app.RainEditField_2Label.Text = {'Rain'; ''};
            app.rainedit = uieditfield(app.Panel_5, 'text');
            app.rainedit.Tooltip = {'Enter Rain Here'};
            app.rainedit.Value = '0.0';
            app.ShowFigureLabel = uilabel(app.GridLayout);
            app.ShowFigureLabel.Text = '  Show Figure';
            app.ButtonGroup = uibuttongroup(app.GridLayout);
            app.sl = uilabel(app.ButtonGroup);
            app.sl.Text = 'Choose Fig. to Show';
            app.dddp = uidropdown(app.ButtonGroup);
            app.dddp.Items = {'CF', 'ROC', 'DataReg'};
            app.dddp.Value = 'CF';
            app.Button = uibutton(app.ButtonGroup, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '👁️‍🗨️';
            app.Panel_4 = uipanel(app.GridLayout);
            app.ml = uilabel(app.Panel_4);
            app.ml.Text = 'Month of the year: ''jan'' to ''dec''';
            app.dl = uilabel(app.Panel_4);
            app.dl.Text = 'Day of the week: ''sun'' to ''sat''';
            app.Label_4 = uilabel(app.Panel_4);
            app.Label_4.Text = '';
            app.mdd = uidropdown(app.Panel_4);
            app.mdd.Items = {'jan', 'feb', 'mar', 'may', 'jun', 'jul', 'aug', 'sep', 'oct'};
            app.mdd.Tooltip = {'Choose Month From Here'};
            app.mdd.Value = 'jan';
            app.ddd = uidropdown(app.Panel_4);
            app.ddd.Items = {'sun', 'mon', 'tue', 'wed', 'thu', 'fri', 'sat'};
            app.ddd.Tooltip = {'Choose Day From Here'};
            app.ddd.Value = 'sun';
            app.MonthLabel = uilabel(app.Panel_4);
            app.MonthLabel.Text = 'Month';
            app.DayLabel = uilabel(app.Panel_4);
            app.DayLabel.Text = 'Day';
            app.Panel_3 = uipanel(app.GridLayout);
            app.Label_3 = uilabel(app.Panel_3);
            app.Label_3.Text = '';
            app.Label_5 = uilabel(app.Panel_3);
            app.Label_5.Text = '';
            app.Label_6 = uilabel(app.Panel_3);
            app.Label_6.Text = '';
            app.FFMCEditFieldLabel = uilabel(app.Panel_3);
            app.FFMCEditFieldLabel.Text = 'FFMC';
            app.ffmcedit = uieditfield(app.Panel_3, 'text');
            app.ffmcedit.Tooltip = {'Enter Fine Fuel Moisture Code Here'};
            app.ffmcedit.Value = '93.5';
            app.fl = uilabel(app.Panel_3);
            app.fl.Text = {'Fine Fuel Moisture Code '; 'From 0.00 to 1090.84'};
            app.dml = uilabel(app.Panel_3);
            app.dml.Text = 'Duff Moisture Code From 1.1 to 291.3';
            app.DMCEditField_2Label = uilabel(app.Panel_3);
            app.DMCEditField_2Label.Text = 'DMC';
            app.dmcedit = uieditfield(app.Panel_3, 'text');
            app.dmcedit.Tooltip = {'Enter Duff Moisture Code Here'};
            app.dmcedit.Value = '149.3';
            app.dcl = uilabel(app.Panel_3);
            app.dcl.Text = 'Drought Code From 7.9 to 860.6';
            app.DCLabel = uilabel(app.Panel_3);
            app.DCLabel.Text = ' DC';
            app.dcedit = uieditfield(app.Panel_3, 'text');
            app.dcedit.Tooltip = {'Enter Drought Code Here'};
            app.dcedit.Value = '728.6';
            app.il = uilabel(app.Panel_3);
            app.il.Text = 'Initial Spread Index From 0.0 to 56.10';
            app.ISILabel = uilabel(app.Panel_3);
            app.ISILabel.Text = '   ISI';
            app.isiedit = uieditfield(app.Panel_3, 'text');
            app.isiedit.Tooltip = {'Enter Initial Spread Index Here'};
            app.isiedit.Value = '8.1';
            app.PredictButton = uibutton(app.chasethefire_1, 'state');
            app.PredictButton.ValueChangedFcn = createCallbackFcn(app, @PredictButtonValueChanged, true);
            app.PredictButton.Text = 'Predict';
            app.arealabel = uilabel(app.chasethefire_1);
            app.arealabel.Text = 'A R E A';
            app.areafinal = uieditfield(app.chasethefire_1, 'text');
            app.areafinal.Editable = 'off';
            app.areafinal.Enable = 'off';
            app.areafinal.Value = '?';
            app.areadet = uilabel(app.chasethefire_1);
            app.areadet.Text = 'The burned area of the forest (in ha): 0.00 to 1090.84';
            app.mapl = uilabel(app.chasethefire_1);
            app.mapl.Text = 'Choose Map Mode Satellite - Maps';
            app.MapsButton = uibutton(app.chasethefire_1, 'state');
            app.MapsButton.ValueChangedFcn = createCallbackFcn(app, @MapsButtonValueChanged, true);
            app.MapsButton.Icon = 'maps.png';
            app.MapsButton.Text = 'Maps';
            app.UIAxes = uiaxes(app.chasethefire_1);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.ZLim = [0 1];
            app.UIAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.TypeFirePerdictionButton = uibutton(app.chasethefire_1, 'state');
            app.TypeFirePerdictionButton.ValueChangedFcn = createCallbackFcn(app, @TypeFirePerdictionButtonValueChanged, true);
            app.TypeFirePerdictionButton.Text = 'Flip Type Fire Perdiction';
            app.chasethefire_2 = uipanel(app.ChaseTheFireUIFigure);
            app.chasethefire_2.Title = 'Chase The Fire';
            app.PredictButton_2 = uibutton(app.chasethefire_2, 'state');
            app.PredictButton_2.ValueChangedFcn = createCallbackFcn(app, @PredictButton_2ValueChanged, true);
            app.PredictButton_2.Text = 'Predict';
            app.arealabel_2 = uilabel(app.chasethefire_2);
            app.arealabel_2.Text = 'T Y P E';
            app.Image2 = uiimage(app.chasethefire_2);
            app.Image2.ImageSource = 'roc0.png';
            app.areafinal_2 = uieditfield(app.chasethefire_2, 'text');
            app.areafinal_2.Editable = 'off';
            app.areafinal_2.Enable = 'off';
            app.areafinal_2.Value = '?';
            app.FlipFireAreaPredictionButton = uibutton(app.chasethefire_2, 'state');
            app.FlipFireAreaPredictionButton.ValueChangedFcn = createCallbackFcn(app, @FlipFireAreaPredictionButtonValueChanged, true);
            app.FlipFireAreaPredictionButton.Text = 'Flip Fire Area Prediction';
            app.Panel_7 = uipanel(app.chasethefire_2);
            app.GridLayout_2 = uigridlayout(app.Panel_7);
            app.SomeFactorsLabel_3 = uilabel(app.GridLayout_2);
            app.SomeFactorsLabel_3.Text = '   Some Factors';
            app.PannelFunctionLabel_2 = uilabel(app.GridLayout_2);
            app.PannelFunctionLabel_2.Text = '  Pannel Function';
            app.TypeFirePerdictionLabel = uilabel(app.GridLayout_2);
            app.TypeFirePerdictionLabel.Text = '🔥 Type Fire Perdiction 🔥';
            app.TemperatureLabel = uilabel(app.GridLayout_2);
            app.TemperatureLabel.Text = '    Temperature';
            app.Panel_8 = uipanel(app.GridLayout_2);
            app.Label_13 = uilabel(app.Panel_8);
            app.Label_13.Text = '';
            app.BrightnessLabel = uilabel(app.Panel_8);
            app.BrightnessLabel.Text = 'Brightness ';
            app.briedit2 = uieditfield(app.Panel_8, 'text');
            app.briedit2.Tooltip = {'Enter Fine Fuel Moisture Code Here'};
            app.briedit2.Value = '317.2';
            app.fl_2 = uilabel(app.Panel_8);
            app.fl_2.Text = 'Brightness temperature 21 (Kelvin)';
            app.dml_2 = uilabel(app.Panel_8);
            app.dml_2.Text = 'Brightness temperature 31 (Kelvin)';
            app.Bright_T31Label = uilabel(app.Panel_8);
            app.Bright_T31Label.Text = 'Bright_T31';
            app.briedit3 = uieditfield(app.Panel_8, 'text');
            app.briedit3.Tooltip = {'Enter Duff Moisture Code Here'};
            app.briedit3.Value = '293.1';
            app.briedit2_2 = uieditfield(app.Panel_8, 'text');
            app.briedit2_2.Tooltip = {'Enter Fine Fuel Moisture Code Here'};
            app.briedit2_2.Value = '293.1';
            app.fl_3 = uilabel(app.Panel_8);
            app.fl_3.Text = 'Brightness temperature 31 (Kelvin)';
            app.bright_t31Label = uilabel(app.Panel_8);
            app.bright_t31Label.Text = 'bright_t31';
            app.Panel_10 = uipanel(app.GridLayout_2);
            app.Label_16 = uilabel(app.Panel_10);
            app.Label_16.Text = '';
            app.Label_17 = uilabel(app.Panel_10);
            app.Label_17.Text = '';
            app.Label_18 = uilabel(app.Panel_10);
            app.Label_18.Text = '';
            app.ScanLabel = uilabel(app.Panel_10);
            app.ScanLabel.Text = 'Scan';
            app.scanedit = uieditfield(app.Panel_10, 'text');
            app.scanedit.Tooltip = {'Enter Temperature Here'};
            app.scanedit.Value = '1.3';
            app.tl_2 = uilabel(app.Panel_10);
            app.tl_2.Text = 'Along Scan pixel size';
            app.rhl_2 = uilabel(app.Panel_10);
            app.rhl_2.Text = 'Along Track pixel size';
            app.TrackLabel = uilabel(app.Panel_10);
            app.TrackLabel.Text = 'Track';
            app.trackedit = uieditfield(app.Panel_10, 'text');
            app.trackedit.Tooltip = {'Enter Relative Humidity Here'};
            app.trackedit.Value = '1.1';
            app.wl_2 = uilabel(app.Panel_10);
            app.wl_2.Text = 'Confidence (0-100%)';
            app.ConfidenceLabel = uilabel(app.Panel_10);
            app.ConfidenceLabel.Text = 'Confidence';
            app.conedit = uieditfield(app.Panel_10, 'text');
            app.conedit.Tooltip = {'Enter Wind Speed Here'};
            app.conedit.Value = '54';
            app.rl_2 = uilabel(app.Panel_10);
            app.rl_2.Text = 'Fire Radiative Power (MW - megawatts)';
            app.FRPLabel = uilabel(app.Panel_10);
            app.FRPLabel.Text = 'FRP';
            app.frpedit = uieditfield(app.Panel_10, 'text');
            app.frpedit.Tooltip = {'Enter Rain Here'};
            app.frpedit.Value = '7.4';
            app.ShowFigureLabel_2 = uilabel(app.GridLayout_2);
            app.ShowFigureLabel_2.Text = '    Show Figure';
            app.ButtonGroup_2 = uibuttongroup(app.GridLayout_2);
            app.xl_4 = uilabel(app.ButtonGroup_2);
            app.xl_4.Text = 'Choose Fig. To Show';
            app.ddd_4 = uidropdown(app.ButtonGroup_2);
            app.ddd_4.Items = {'Confusion Matrix', 'Data Before & After'};
            app.ddd_4.Tooltip = {'Choose Day From Here'};
            app.ddd_4.Value = 'Confusion Matrix';
            app.Button_2 = uibutton(app.ButtonGroup_2, 'push');
            app.Button_2.Text = '👁️‍🗨️';
            app.DimensionsLabel = uilabel(app.GridLayout_2);
            app.DimensionsLabel.Text = '     Dimensions';
            app.Panel_12 = uipanel(app.GridLayout_2);
            app.latedit = uieditfield(app.Panel_12, 'text');
            app.latedit.Tooltip = {'Enter X-Axis Here'};
            app.latedit.Value = '-9.6863';
            app.LatitudeLabel = uilabel(app.Panel_12);
            app.LatitudeLabel.Text = 'Latitude';
            app.xl_3 = uilabel(app.Panel_12);
            app.xl_3.Text = {'Center of 1km fire pixel but not necessarily the actual location '; 'of the fire as one or more fires can be detected within the 1km pixel.'};
            app.LongitudeLabel = uilabel(app.Panel_12);
            app.LongitudeLabel.Text = 'Longitude';
            app.laonedit = uieditfield(app.Panel_12, 'text');
            app.laonedit.Tooltip = {'Enter Y-Axis Here'};
            app.laonedit.Value = '149.5227';
            app.Label_20 = uilabel(app.Panel_12);
            app.Label_20.Text = '';
            app.MoreDetailsLabel = uilabel(app.GridLayout_2);
            app.MoreDetailsLabel.Text = '    More Details';
            app.Panel_9 = uipanel(app.GridLayout_2);
            app.SatelliteLabel = uilabel(app.Panel_9);
            app.SatelliteLabel.Text = 'Satellite';
            app.xl_2 = uilabel(app.Panel_9);
            app.xl_2.Text = 'Choose Satellite Type Aqua or Terra';
            app.Label_15 = uilabel(app.Panel_9);
            app.Label_15.Text = '';
            app.sdd = uidropdown(app.Panel_9);
            app.sdd.Items = {'Terra', 'Aqua'};
            app.sdd.Tooltip = {'Choose Day From Here'};
            app.sdd.Value = 'Terra';
            app.timedit = uieditfield(app.Panel_9, 'text');
            app.timedit.Tooltip = {'Enter Rain Here'};
            app.timedit.Value = '13';
            app.AcqTimeLabel_2 = uilabel(app.Panel_9);
            app.AcqTimeLabel_2.Text = 'Acq. Time';
            app.DayNightLabel = uilabel(app.Panel_9);
            app.DayNightLabel.Text = 'Day / Night';
            app.tdd = uidropdown(app.Panel_9);
            app.tdd.Items = {'Day', 'Night'};
            app.tdd.Tooltip = {'Choose Day From Here'};
            app.tdd.Value = 'Day';
            app.StatusBarLabel = uilabel(app.ChaseTheFireUIFigure);
            app.StatusBarLabel.Text = '      StatusBar';
            app.ChasersAllRightsReservedLabel = uilabel(app.ChaseTheFireUIFigure);
            app.ChasersAllRightsReservedLabel.Text = '               © 2020 Chasers All Rights Reserved';
            app.DateLabel = uilabel(app.ChaseTheFireUIFigure);
            app.DateLabel.Text = 'Date : ';
            app.Label_10 = uilabel(app.ChaseTheFireUIFigure);
            app.Label_10.Text = '14-5-2020 ';
            app.Image = uiimage(app.ChaseTheFireUIFigure);
            app.Image.Tooltip = {'Chaseres'};
            app.Image.ImageSource = 'logo.png';
            % Show the figure after all components are created
            app.ChaseTheFireUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ChaseTheFireUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ChaseTheFireUIFigure)
        end
    end
end